classdef GBM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        UIAxes          matlab.ui.control.UIAxes
        RightPanel      matlab.ui.container.Panel
        Label           matlab.ui.control.Label
        inputmiu        matlab.ui.control.Spinner
        Spinner_2Label  matlab.ui.control.Label
        inputsigma      matlab.ui.control.Spinner
        Simulate        matlab.ui.control.Button
        S0SpinnerLabel  matlab.ui.control.Label
        inputS0         matlab.ui.control.Spinner
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: inputmiu
        function inputmiuValueChanged(app, event)
            mu = app.inputmiu.Value;
            
        end

        % Value changed function: inputsigma
        function inputsigmaValueChanged(app, event)
            sigma = app.inputsigma.Value;
            
        end

        % Callback function: LeftPanel, Simulate
        function SimulateButtonPushed(app, event)
            mu    = app.inputmiu.Value;
            sigma = app.inputsigma.Value;
            S0    = app.inputS0.Value;
            T = 2;
            dt=0.01;
            step=round(T/dt);
            t = linspace(0,T,step);
            N = randn([1,step]);
            W = cumsum(N)*sqrt(dt);
            % generate the random value
            Bt = (mu-0.5*sigma^2)*t + sigma*W;
            St = S0*exp(Bt);
            plot(app.UIAxes,t,St,"LineWidth",2)
        end

        % Value changed function: inputS0
        function inputS0ValueChanged(app, event)
            S0 = app.inputS0.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {325, 325};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {409, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.SizeChangedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'dSt=μStdt+σStdWt')
            xlabel(app.UIAxes, 'Time t')
            ylabel(app.UIAxes, 'Value St')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.UIAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'; '1.2'; '1.4'; '1.6'; '1.8'; '2'};
            app.RightPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = 'μ';
            app.inputmiu = uispinner(app.RightPanel);
            app.inputmiu.Step = 0.01;
            app.inputmiu.ValueChangedFcn = createCallbackFcn(app, @inputmiuValueChanged, true);
            app.inputmiu.Value = 0.1;
            app.Spinner_2Label = uilabel(app.RightPanel);
            app.Spinner_2Label.Text = 'σ';
            app.inputsigma = uispinner(app.RightPanel);
            app.inputsigma.Step = 0.01;
            app.inputsigma.ValueChangedFcn = createCallbackFcn(app, @inputsigmaValueChanged, true);
            app.inputsigma.Value = 0.06;
            app.Simulate = uibutton(app.RightPanel, 'push');
            app.Simulate.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.Simulate.Text = 'Simulate';
            app.S0SpinnerLabel = uilabel(app.RightPanel);
            app.S0SpinnerLabel.Text = 'S0';
            app.inputS0 = uispinner(app.RightPanel);
            app.inputS0.Step = 0.1;
            app.inputS0.ValueChangedFcn = createCallbackFcn(app, @inputS0ValueChanged, true);
            app.inputS0.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GBM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end